classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        StartButton              matlab.ui.control.Button
        FlipPageButton           matlab.ui.control.Button
        CalbrationButton         matlab.ui.control.Button
        AutomodeButton           matlab.ui.control.StateButton
        ROBOTARMCONTROLAPPLabel  matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        ConnectionSwitchLabel    matlab.ui.control.Label
        ConnectionSwitch         matlab.ui.control.ToggleSwitch
        CalculationButton        matlab.ui.control.Button
        PowerSwitchLabel         matlab.ui.control.Label
        PowerSwitch              matlab.ui.control.ToggleSwitch
    end


    methods (Access = private)

        % Button pushed function: CalculationButton
        function CalculationButtonPushed(app, event)
X=27.5; %cm
Y=50;%cm
Z=17;%cm
h=10;%cm
dx=3.3; % the unit is cm
dy=3.6;%the unit is cm
L1=32; %the unit is cm
L2=35;%the unit is cm
m=11;
n=8;

joint0=zeros(n,m);
joint1=zeros(n,m);
joint2=zeros(n,m);
joint3=zeros(n,m);
joint1_1=zeros(n,m);
joint2_1=zeros(n,m);
joint3_1=zeros(n,m);
for i=1:m
for j=1:n
joint0(i,j)=rad2deg(atan((Y-(i-1)*dy)/(X-(j-1)*dx)));
%joint position of measurement
L3=sqrt((Y-(i-1)*dy)^2+(X-(j-1)*dx)^2+Z^2);
joint1(i,j)=rad2deg(acos((L1^2+L3^2-L2^2)/(2*L1*L3)));
joint2(i,j)=rad2deg(acos((L1^2+L2^2-L3^2)/(2*L1*L2)));
joint3(i,j)=rad2deg(acos((L2^2+L3^2-L1^2)/(2*L2*L3)))+rad2deg(atan((Z)/(sqrt(X^2+Y^2))))+90;
%mines the calibration angle
joint1(i,j)=joint1(i,j)-64;
joint2(i,j)=joint2(i,j)-64;
joint3(i,j)=joint3(i,j)-180;
% transitional position
L3_1=sqrt((Y-(i-1)*dy)^2+(X-(j-1)*dx)^2+(Z-h)^2);
joint1_1(i,j)=rad2deg(acos((L1^2+L3_1^2-L2^2)/(2*L1*L3_1)));
joint2_1(i,j)=rad2deg(acos((L1^2+L2^2-L3_1^2)/(2*L1*L2)));
joint3_1(i,j)=rad2deg(acos((L2^2+L3^2-L1^2)/(2*L2*L3)))+rad2deg(atan((Z)/(sqrt(X^2+Y^2))))+90;
%after calibration
joint1_1(i,j)=joint1_1(i,j)-64;
joint2_1(i,j)=joint2_1(i,j)-64;
joint3_1(i,j)=joint3_1(i,j)-180;
end
end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
global x;
x=serial('COM7','BAUD',9600);
disp("Sending Communication Requist");
fopen(x);
disp("Connected");
disp("connection success");
TEST=strcat(num2str(90),',',num2str(10),',',num2str(10),',',num2str(10),',',num2str(1),',',num2str(1));
fprintf(x,TEST);
pause(2);
fclose(x);

        end

        % Button pushed function: FlipPageButton
        function FlipPageButtonPushed(app, event)
sw=0;
Pose1=strcat(num2str(0),',',num2str(0),',',num2str(0),',',num2str(0),',',num2str(dt),',',num2str(sw));
Pose2=strcat(num2str(0),',',num2str(0),',',num2str(0),',',num2str(0),',',num2str(dt),',',num2str(sw));
Pose3=strcat(num2str(0),',',num2str(0),',',num2str(0),',',num2str(0),',',num2str(dt),',',num2str(sw));
fprintf(x,Pose1);
fprintf(x,Pose2);
fprintf(x,Pose3);           
        end

        % Button pushed function: CalbrationButton
        function CalbrationButtonPushed(app, event)
sw=0;
CA=strcat(num2str(0),',',num2str(64),',',num2str(64),',',num2str(180),',',num2str(5),',',num2str(sw));
fprintf(x,CA);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
fclose(x);
        end

        % Value changed function: ConnectionSwitch
        function ConnectionSwitchValueChanged(app, event)
            value = app.ConnectionSwitch.Value;
disp("Communication requist...");
x=serial('COM4','BAUD',9600);
            if value==1
fopen(x);
ComRequist = "0";
fprintf(x,ComRequist);
delay(100);
y=fscanf(x);
%disp the check result
if y==1
    disp("Communication success");
else
    disp("Communication fail");
end
            else
fclose(x);              
            end
        end

        % Value changed function: AutomodeButton
        function AutomodeButtonValueChanged(app, event)
            value = app.AutomodeButton.Value;
            if value==1
X=27.5; %cm
Y=50;%cm
Z=17;%cm
h=10;%cm
dx=3.3; % the unit is cm
dy=3.6;%the unit is cm
L1=32; %the unit is cm
L2=35;%the unit is cm
m=11;
n=8;
dt=1;

joint0=zeros(n,m);
joint1=zeros(n,m);
joint2=zeros(n,m);
joint3=zeros(n,m);
joint1_1=zeros(n,m);
joint2_1=zeros(n,m);
joint3_1=zeros(n,m);
for i=1:m
for j=1:n
joint0(i,j)=rad2deg(atan((Y-(i-1)*dy)/(X-(j-1)*dx)));
%joint position of measurement
L3=sqrt((Y-(i-1)*dy)^2+(X-(j-1)*dx)^2+Z^2);
joint1(i,j)=rad2deg(acos((L1^2+L3^2-L2^2)/(2*L1*L3)));
joint2(i,j)=rad2deg(acos((L1^2+L2^2-L3^2)/(2*L1*L2)));
joint3(i,j)=rad2deg(acos((L2^2+L3^2-L1^2)/(2*L2*L3)))+rad2deg(atan((Z)/(sqrt(X^2+Y^2))))+90;
%mines the calibration angle
joint1(i,j)=joint1(i,j)-64;
joint2(i,j)=joint2(i,j)-64;
joint3(i,j)=joint3(i,j)-180;
% transitional position
L3_1=sqrt((Y-(i-1)*dy)^2+(X-(j-1)*dx)^2+(Z-h)^2);
joint1_1(i,j)=rad2deg(acos((L1^2+L3_1^2-L2^2)/(2*L1*L3_1)));
joint2_1(i,j)=rad2deg(acos((L1^2+L2^2-L3_1^2)/(2*L1*L2)));
joint3_1(i,j)=rad2deg(acos((L2^2+L3^2-L1^2)/(2*L2*L3)))+rad2deg(atan((Z)/(sqrt(X^2+Y^2))))+90;
%after calibration
joint1_1(i,j)=joint1_1(i,j)-64;
joint2_1(i,j)=joint2_1(i,j)-64;
joint3_1(i,j)=joint3_1(i,j)-180;
end
end
                %comunication check
disp("Communication requist...");
x=serial('COM4','BAUD',9600);
fopen(x);
ComRequist = "0";
fprintf(x,ComRequist);
delay(100);
y=fscanf(x);
%disp the check result
if y==1
    disp("Communication success");
else
    disp("Communication fail");
end

%%
%begin to send the data
    %TP is transitional position data
    %MP is measurement position data
    %sw is the air generator switch 0 is close, 1 is open
%scan the right half page
for i=1:m
for j=1:n
sw=0;   
MP=strcat(num2str(joint0(i,j)),',',num2str(joint1(i,j)),',',num2str(joint2(i,j)),',',num2str(joint3(i,j)),',',num2str(dt),',',num2str(sw));
TP=strcat(num2str(joint0(i,j)),',',num2str(joint1_1(i,j)),',',num2str(joint2_1(i,j)),',',num2str(joint3_1(i,j)),',',num2str(dt),',',num2str(sw));
fprintf(x,TP);
fprintf(x,MP);
fprintf(x,TP);
end
end

%scan the left half page
for i=1:m
for j=1:n
sw=0;   
MP=strcat(num2str(180-joint0(i,j)),',',num2str(joint1(i,j)),',',num2str(joint2(i,j)),',',num2str(joint3(i,j)),',',num2str(dt),',',num2str(sw));
TP=strcat(num2str(180-joint0(i,j)),',',num2str(joint1_1(i,j)),',',num2str(joint2_1(i,j)),',',num2str(joint3_1(i,j)),',',num2str(dt),',',num2str(sw));
fprintf(x,TP);
fprintf(x,MP);
fprintf(x,TP);
end
end
 
%flip page
sw=0;
Pose1=strcat(num2str(180-joint0(i,j)),',',num2str(joint1(i,j)),',',num2str(joint2(i,j)),',',num2str(joint3(i,j)),',',num2str(dt),',',num2str(sw));
Pose2=strcat(num2str(180-joint0(i,j)),',',num2str(joint1(i,j)),',',num2str(joint2(i,j)),',',num2str(joint3(i,j)),',',num2str(dt),',',num2str(sw));
Pose3=strcat(num2str(180-joint0(i,j)),',',num2str(joint1(i,j)),',',num2str(joint2(i,j)),',',num2str(joint3(i,j)),',',num2str(dt),',',num2str(sw));
fprintf(x,Pose1);
fprintf(x,Pose2);
fprintf(x,Pose3);

%calibrition sensor
%the calibration position is 0,64,64,180
sw=0;
CA=strcat(num2str(0),',',num2str(64),',',num2str(64),',',num2str(180),',',num2str(5),',',num2str(sw));
fprintf(x,CA);
y=fscanf(x);
if y==2
    disp("Scan finished");
end
disp("Stop sending the requist");
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start ';
            app.FlipPageButton = uibutton(app.UIFigure, 'push');
            app.FlipPageButton.ButtonPushedFcn = createCallbackFcn(app, @FlipPageButtonPushed, true);
            app.FlipPageButton.Text = 'Flip Page';
            app.CalbrationButton = uibutton(app.UIFigure, 'push');
            app.CalbrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalbrationButtonPushed, true);
            app.CalbrationButton.Text = 'Calbration';
            app.AutomodeButton = uibutton(app.UIFigure, 'state');
            app.AutomodeButton.ValueChangedFcn = createCallbackFcn(app, @AutomodeButtonValueChanged, true);
            app.AutomodeButton.Text = 'Auto mode';
            app.ROBOTARMCONTROLAPPLabel = uilabel(app.UIFigure);
            app.ROBOTARMCONTROLAPPLabel.Text = 'ROBOT ARM CONTROL APP';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ConnectionSwitchLabel = uilabel(app.UIFigure);
            app.ConnectionSwitchLabel.Text = 'Connection';
            app.ConnectionSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ConnectionSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectionSwitchValueChanged, true);
            app.CalculationButton = uibutton(app.UIFigure, 'push');
            app.CalculationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculationButtonPushed, true);
            app.CalculationButton.Text = 'Calculation';
            app.PowerSwitchLabel = uilabel(app.UIFigure);
            app.PowerSwitchLabel.Text = 'Power';
            app.PowerSwitch = uiswitch(app.UIFigure, 'toggle');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end